classdef Currency_Recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LoadImageButton     matlab.ui.control.Button
        AddtoDBButton       matlab.ui.control.Button
        ReadCurrencyButton  matlab.ui.control.Button
        TextArea            matlab.ui.control.TextArea
        EditField           matlab.ui.control.EditField
        TextArea_2          matlab.ui.control.TextArea
        UIAxes              matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function fet=totalfeature(app,rgbim)
         %color feature
         fet1=color_luv(app,rgbim);
         %edge feature
         fet2=edgehist(app,rgbim);
         %texture feature
         %glcm-gray level co occurrence matrix
         glcm=graycomatrix(rgb2gray(rgbim));
         fet3=glcm(:);
         fet=[fet1;fet2;fet3];
        end
        
        function colfet=color_luv(~,rgb_im)
            %three color moments,mean,color variance and color skewness in each channel
            %(L,U and V respectively)
            %total 9-D color moments
            %Input image should be a rgb image,
            
            %convert rgb to xyz colorspace
            xyzTransformation = makecform('srgb2xyz');
            xyzI = applycform(rgb_im,xyzTransformation);
            %convert xyz to luv colorspace
            warning('off');
            luvTransformation = makecform('xyz2uvl');
            luvI = applycform(xyzI,luvTransformation);
            
            %figure,imshow(luvI,'initialmagnification','fit');
            %seperate l,u,v
            L=luvI(:,:,1);
            U=luvI(:,:,2);
            V=luvI(:,:,3);
            
            %find mean,color variance and color skewness for each channel
            
             colfet(1)= mean(L(:));
             colfet(2) = std(L(:))^2;
             colfet(3)= skewness(L(:));
                colfet(4) = mean(U(:));
                colfet(5) = std(U(:))^2;
                colfet(6) = skewness(U(:));
                colfet(7) = mean(V(:));
                colfet(8) = std(V(:))^2;
               colfet(9) = skewness(V(:));
            
            colfet=colfet';
           end

        
        function edhist= edgehist(~,rgb_im)


            %edge directional histogram
            %Input image should be a rgb image,
            %a 1x5 edge orientation histogram is computed (horizontal, vertical,
            % 2 diagonals and 1 non-directional)
            
            
            %convert rgb color space into ycbcr colorspace
            
            new_im=rgb2ycbcr(rgb_im);
            %extract only y component
            
            y=double(new_im(:,:,1));
            
            %figure,imshow(y,'initialmagnification','fit');
            
            % define the filters for the 5 types of edges
            f1 = zeros(3,3,5);
            f1(:,:,1) = [1 2 1;0 0 0;-1 -2 -1]; %vertical
            f1(:,:,2) = [-1 0 1;-2 0 2;-1 0 1];   %horizontal
            f1(:,:,3) = [2 2 -1;2 -1 -1; -1 -1 -1];% 45 diagonal
            f1(:,:,4) = [-1 2 2; -1 -1 2; -1 -1 -1];%135 diagonal
            f1(:,:,5) = [-1 0 1;0 0 0;1 0 -1]; % non directional
            
            % iterate over the posible directions
            for i = 1:5
            % apply the sobel mask
            g_im(:,:,i) = filter2(f1(:,:,i),y);
            end
            % calculate the max sobel gradient and index  of the orientation
            [m, p] = max(g_im,[],3);
            
            %detect the edges using canny
            edim = edge(y, 'canny');
            
            %figure,imshow(edim,'initialmagnification','fit');
            %multiply edge image with the types of orientations detected
            % by the Sobel masks
            im2 =(p.*edim);
            
            %find hisogram
            edhist=hist(im2(:),5)';
            
            end

        
    end


    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global im;
            [imname, impath] = uigetfile('*.*', 'Pick an Image');
            im=imread([impath,'/',imname]);
            imshow(im,'Parent',app.UIAxes);
        end

        % Button pushed function: ReadCurrencyButton
        function ReadCurrencyButtonPushed(app, event)
            global im;
            t=im;
            global ans1;
            %resize image
            t=imresize(t,[128 128]);
            %remove noise;
            %seperate channels
             r_channel=t(:,:,1);
             b_channel=t(:,:,2);
             g_channel=t(:,:,3);
             %denoise each channel
             r_channel=medfilt2(r_channel);
             g_channel=medfilt2(g_channel);
             b_channel=medfilt2(b_channel);
             %restore channels
             rgbim(:,:,1)=r_channel;
             rgbim(:,:,2)=g_channel;
             rgbim(:,:,3)=b_channel;
             %featureextraction
            fet=totalfeature(app,rgbim);
            load db;
            k=length(currency);
            for j=1:k
            D(j)=dist(fet',currency{j,1});
            end
            [value,index]=min(D);
            if value>=0 && value<1000
            currency_name=currency(index ,2);
            ans1=currency_name;
            else
            ans1='no matches found';
            end
            set(app.TextArea_2,'Value',ans1);
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
           
        end

        % Button pushed function: AddtoDBButton
        function AddtoDBButtonPushed(app, event)
            %resize image
            global im;
                     
            a=get(app.TextArea,'Value');
            a=a{1};
            
            j=im;
            j=imresize(j,[128 128]);
            %remove noise;
            %seperate channels
             r_channel=j(:,:,1);
             b_channel=j(:,:,2);
             g_channel=j(:,:,3);
             %denoise each channel
             r_channel=medfilt2(r_channel);
             g_channel=medfilt2(g_channel);
             b_channel=medfilt2(b_channel);
             %restore channels
             rgbim(:,:,1)=r_channel;
             rgbim(:,:,2)=g_channel;
             rgbim(:,:,3)=b_channel;
             %featureextraction
            fet=totalfeature(app,rgbim);
            load db;
            k=length(currency);
            try
                currency(k,1) = {fet};
                currency(k,2) = {a};
                save db.mat currency
            catch 
                currency(k,1) = {fet};
                currency(k,2) = {a};
                save db.mat currency
            end
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
                   
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.AddtoDBButton = uibutton(app.UIFigure, 'push');
            app.AddtoDBButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoDBButtonPushed, true);
            app.AddtoDBButton.Text = 'Add to DB';
            app.ReadCurrencyButton = uibutton(app.UIFigure, 'push');
            app.ReadCurrencyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadCurrencyButtonPushed, true);
            app.ReadCurrencyButton.Text = 'Read Currency';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '   Currency Recognition System';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Currency_Recognition

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end